classdef AssessGridwalkFaults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        PlaybackRateSpinner         matlab.ui.control.Spinner
        PlaybackRateSpinnerLabel    matlab.ui.control.Label
        DetectedFaultsListBox       matlab.ui.control.ListBox
        DetectedFaultsListBoxLabel  matlab.ui.control.Label
        ExportFaultsButton          matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        RemoveFaultButton           matlab.ui.control.Button
        StepForwardsButton          matlab.ui.control.Button
        StepbackwardsButton         matlab.ui.control.Button
        VideoProgressSlider         matlab.ui.control.Slider
        VideoProgressSliderLabel    matlab.ui.control.Label
        AddFaultButton              matlab.ui.control.Button
        FaultLocationButtonGroup    matlab.ui.container.ButtonGroup
        HindRightButton             matlab.ui.control.RadioButton
        HindLeftButton              matlab.ui.control.RadioButton
        FrontRightButton            matlab.ui.control.RadioButton
        FrontLeftButton             matlab.ui.control.RadioButton
        SelectVideoButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Developed by Jeremy Ford, 2023
        Variables=struct(); % Description
    end

    methods (Access = private)

        function results = PlotFrame(app)
            image(app.UIAxes,app.Variables.CurrentInfo.Frame);

            current_fault=find(app.Variables.Faults.Time==app.Variables.CurrentInfo.Time);
            if ~isempty(current_fault)
                switch app.Variables.Faults.Paw(current_fault)
                    case 1
                        title_text = 'Front Left Fault';
                    case 2
                        title_text = 'Front Right Fault';
                    case 3
                        title_text = 'Hind Left Fault';
                    case 4
                        title_text = 'Hind Right Fault';
                end
                app.UIAxes.Title.String=title_text;
            else
                app.UIAxes.Title.String=' ';
            end
            app.Label.Text=GetTimeString(app,app.Variables.CurrentInfo.Time);
            UpdateSlider(app)
        end

        function results = UpdateSlider(app)
            app.VideoProgressSlider.Value=app.Variables.CurrentInfo.Time/app.Variables.VideoDuration;
        end
    end

    methods (Access = public)

        function results = UpdateFaultList(app)

            if ~isempty(app.Variables.Faults.Time)
                for ii=1:length(app.Variables.Faults.Paw)
                    switch app.Variables.Faults.Paw(ii)
                        case 1
                            paw_text = 'FL Fault';
                        case 2
                            paw_text = 'FR Fault';
                        case 3
                            paw_text = 'HL Fault';
                        case 4
                            paw_text = 'HR Fault';
                    end
                    Temp_Faults{ii}=[GetTimeString(app,app.Variables.Faults.Time(ii)),'    ',paw_text];
                end

                app.DetectedFaultsListBox.Items=Temp_Faults;
            else
                app.DetectedFaultsListBox.Items={'No Faults Defined'};
                app.DetectedFaultsListBox.Value={'No Faults Defined'};
            end
        end

        function OutString = GetTimeString(app,TimeInSec)
            TotalSec=num2str(round(mod(TimeInSec,60)),'%0.2i');
            if floor(TimeInSec/60)>=60
                TotalMin=[num2str(mode(floor(TimeInSec/60),60),'%0.2i'),':'];
                TotalHr=[num2str(floor(floor(TimeInSec/60)/60)),':'];
            else
                TotalMin=[num2str(floor(TimeInSec/60),'%0.2i'),':'];
                TotalHr=[''];
            end

            OutString=[TotalHr,TotalMin,TotalSec];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Variables.VideoChosen=0;
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)

            [file,path]=uigetfile('*');
            app.Variables.VideoFile=file;
            app.Variables.Path=path;
            app.Variables.VideoChosen=1;
            app.Variables.VideoObj = VideoReader([path,filesep,file]);
            app.Variables.FR=app.Variables.VideoObj.FrameRate;
            app.Variables.VideoDuration=app.Variables.VideoObj.Duration;
            app.Variables.NumFrames=app.Variables.VideoObj.NumFrames;
            app.Variables.CurrentInfo.Time=app.Variables.VideoObj.CurrentTime;
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            app.Variables.CurrentInfo.PlaybackRate=1;
            app.Variables.CurrentInfo.InterruptPlay=0;
            app.Variables.Faults.Time=[];
            app.Variables.Faults.Paw=[];
            app.Label.Text='0:00';

            app.Label_2.Text = GetTimeString(app,app.Variables.VideoDuration);
            PlotFrame(app)

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.Variables.CurrentInfo.InterruptPlay=0;
            while hasFrame(app.Variables.VideoObj)
                if app.Variables.CurrentInfo.InterruptPlay
                    break
                else
                    app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
                    app.Variables.CurrentInfo.Time=app.Variables.VideoObj.CurrentTime;
                    PlotFrame(app)
                    pause(1/(app.Variables.CurrentInfo.PlaybackRate*app.Variables.FR))
                end
            end
        end

        % Value changed function: VideoProgressSlider
        function VideoProgressSliderValueChanged(app, event)
            value = app.VideoProgressSlider.Value;

            app.Variables.CurrentInfo.Time= value*app.Variables.VideoDuration;
            app.Variables.VideoObj.CurrentTime=app.Variables.CurrentInfo.Time;
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            PlotFrame(app)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.Variables.CurrentInfo.InterruptPlay=1;
        end

        % Button pushed function: StepForwardsButton
        function StepForwardsButtonPushed(app, event)
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            app.Variables.CurrentInfo.Time=app.Variables.VideoObj.CurrentTime;
            PlotFrame(app)
        end

        % Button pushed function: StepbackwardsButton
        function StepbackwardsButtonPushed(app, event)
            app.Variables.VideoObj.CurrentTime=app.Variables.CurrentInfo.Time-(2/app.Variables.FR);
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            app.Variables.CurrentInfo.Time=app.Variables.VideoObj.CurrentTime;
            PlotFrame(app)
        end

        % Button pushed function: AddFaultButton
        function AddFaultButtonPushed(app, event)
            switch app.FaultLocationButtonGroup.SelectedObject.Text
                case 'Front Left'
                    current_fault=1;
                case 'Front Right'
                    current_fault=2;
                case 'Hind Left'
                    current_fault=3;
                case 'Hind Right'
                    current_fault=4;
            end
            fault_times = [app.Variables.Faults.Time app.Variables.CurrentInfo.Time];
            fault_paws= [app.Variables.Faults.Paw  current_fault];

            [app.Variables.Faults.Time,isort]=sort(fault_times);
            app.Variables.Faults.Paw=fault_paws(isort);

            UpdateFaultList(app);

            %             app.DetectedFaultsListBox.Items=[];

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    if app.Variables.VideoChosen
                        StepbackwardsButtonPushed(app)
                    end
                case 'rightarrow'
                    if app.Variables.VideoChosen
                        StepForwardsButtonPushed(app)
                    end
            end

            pause(1/app.Variables.FR)
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            PlotFrame(app)
        end

        % Value changed function: PlaybackRateSpinner
        function PlaybackRateSpinnerValueChanged(app, event)
            value = app.PlaybackRateSpinner.Value;
            app.Variables.CurrentInfo.PlaybackRate=value;
        end

        % Value changed function: DetectedFaultsListBox
        function DetectedFaultsListBoxValueChanged(app, event)
            value = app.DetectedFaultsListBox.Value;

            FaultSelected=find(strcmp(value,app.DetectedFaultsListBox.Items),1);
            app.Variables.VideoObj.CurrentTime=app.Variables.Faults.Time(FaultSelected);
            app.Variables.CurrentInfo.Time=app.Variables.Faults.Time(FaultSelected);
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            PlotFrame(app)
        end

        % Button pushed function: RemoveFaultButton
        function RemoveFaultButtonPushed(app, event)

            app.Variables.Faults.Paw(app.Variables.CurrentInfo.Time==app.Variables.Faults.Time)=[];
            app.Variables.Faults.Time(app.Variables.CurrentInfo.Time==app.Variables.Faults.Time)=[];

            UpdateFaultList(app);

        end

        % Clicked callback: DetectedFaultsListBox
        function DetectedFaultsListBoxClicked(app, event)
            %             item = event.InteractionInformation.Item;
            value = app.DetectedFaultsListBox.Value;

            FaultSelected=find(strcmp(value,app.DetectedFaultsListBox.Items),1);
            app.Variables.VideoObj.CurrentTime=app.Variables.Faults.Time(FaultSelected);
            app.Variables.CurrentInfo.Time=app.Variables.Faults.Time(FaultSelected);
            app.Variables.CurrentInfo.Frame=readFrame(app.Variables.VideoObj);
            PlotFrame(app)
        end

        % Button pushed function: ExportFaultsButton
        function ExportFaultsButtonPushed(app, event)

            for ii=1:length(app.Variables.Faults.Time)

                OutCell{ii,1}=app.Variables.Faults.Time(ii);
                OutCell{ii,2} = GetTimeString(app,app.Variables.Faults.Time(ii));
                OutCell{ii,3} = app.Variables.Faults.Paw(ii);

                               switch app.Variables.Faults.Paw(ii)
                    case 1
                        paw_text = 'Front Left';
                    case 2
                        paw_text = 'Front Right';
                    case 3
                        paw_text = 'Hind Left';
                    case 4
                        paw_text = 'Hind Right';
                end


                OutCell{ii,4}=paw_text;
            end

%Summary Statistics
Total_Faults=length(app.Variables.Faults.Time);
Total_FL_Faults=sum(app.Variables.Faults.Paw==1);
Total_FR_Faults=sum(app.Variables.Faults.Paw==2);
Total_HL_Faults=sum(app.Variables.Faults.Paw==3);
Total_HR_Faults=sum(app.Variables.Faults.Paw==4);
Total_Left_Side=Total_FL_Faults+Total_HL_Faults;
Total_Right_Side=Total_FR_Faults+Total_HR_Faults;

OutCell{1,5}=Total_Faults;
OutCell{1,6}=Total_FL_Faults;
OutCell{1,7}=Total_FR_Faults;
OutCell{1,8}=Total_HL_Faults;
OutCell{1,9}=Total_HR_Faults;
OutCell{1,10}=Total_Left_Side;
OutCell{1,11}=Total_Right_Side;


            OutTab = cell2table(OutCell,'VariableNames',["Time_In_Seconds" "Time" "Fault_Location" "Fault_Location_PlainText" "Total_Faults" "Total_FL_Faults"...
                "Total_FR_Faults" "Total_HL_Faults" "Total_HR_Faults" "Total_Left_Side" "Total_Right_Side"]);

            extension_location = strfind(app.Variables.VideoFile,'.');
            if isempty(extension_location)
                filename=app.Variables.VideoFile;
            else
                filename=app.Variables.VideoFile(1:extension_location-1);
            end
            writetable(OutTab,[app.Variables.Path,filename,'FaultResults.csv']);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectVideoButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.FaultLocationButtonGroup = uibuttongroup(app.UIFigure);
            app.FaultLocationButtonGroup.Title = 'Fault Location';
            app.FrontLeftButton = uiradiobutton(app.FaultLocationButtonGroup);
            app.FrontLeftButton.Text = 'Front Left';
            app.FrontLeftButton.Value = true;
            app.FrontRightButton = uiradiobutton(app.FaultLocationButtonGroup);
            app.FrontRightButton.Text = 'Front Right';
            app.HindLeftButton = uiradiobutton(app.FaultLocationButtonGroup);
            app.HindLeftButton.Text = 'Hind Left';
            app.HindRightButton = uiradiobutton(app.FaultLocationButtonGroup);
            app.HindRightButton.Text = 'Hind Right';
            app.AddFaultButton = uibutton(app.UIFigure, 'push');
            app.AddFaultButton.ButtonPushedFcn = createCallbackFcn(app, @AddFaultButtonPushed, true);
            app.AddFaultButton.Text = 'Add Fault';
            app.VideoProgressSliderLabel = uilabel(app.UIFigure);
            app.VideoProgressSliderLabel.Text = 'Video Progress';
            app.VideoProgressSlider = uislider(app.UIFigure);
            app.VideoProgressSlider.Limits = [0 1];
            app.VideoProgressSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.VideoProgressSlider.MajorTickLabels = {''};
            app.VideoProgressSlider.ValueChangedFcn = createCallbackFcn(app, @VideoProgressSliderValueChanged, true);
            app.VideoProgressSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.StepbackwardsButton = uibutton(app.UIFigure, 'push');
            app.StepbackwardsButton.ButtonPushedFcn = createCallbackFcn(app, @StepbackwardsButtonPushed, true);
            app.StepbackwardsButton.Text = 'Step backwards';
            app.StepForwardsButton = uibutton(app.UIFigure, 'push');
            app.StepForwardsButton.ButtonPushedFcn = createCallbackFcn(app, @StepForwardsButtonPushed, true);
            app.StepForwardsButton.Text = 'Step Forwards';
            app.RemoveFaultButton = uibutton(app.UIFigure, 'push');
            app.RemoveFaultButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFaultButtonPushed, true);
            app.RemoveFaultButton.Text = 'Remove Fault';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ExportFaultsButton = uibutton(app.UIFigure, 'push');
            app.ExportFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFaultsButtonPushed, true);
            app.ExportFaultsButton.Text = 'Export Faults';
            app.DetectedFaultsListBoxLabel = uilabel(app.UIFigure);
            app.DetectedFaultsListBoxLabel.Text = 'Detected Faults';
            app.DetectedFaultsListBox = uilistbox(app.UIFigure);
            app.DetectedFaultsListBox.Items = {'No Faults Defined'};
            app.DetectedFaultsListBox.ValueChangedFcn = createCallbackFcn(app, @DetectedFaultsListBoxValueChanged, true);
            app.DetectedFaultsListBox.ClickedFcn = createCallbackFcn(app, @DetectedFaultsListBoxClicked, true);
            app.DetectedFaultsListBox.Value = 'No Faults Defined';
            app.PlaybackRateSpinnerLabel = uilabel(app.UIFigure);
            app.PlaybackRateSpinnerLabel.Text = 'Playback Rate';
            app.PlaybackRateSpinner = uispinner(app.UIFigure);
            app.PlaybackRateSpinner.Limits = [0.25 4];
            app.PlaybackRateSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaybackRateSpinnerValueChanged, true);
            app.PlaybackRateSpinner.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AssessGridwalkFaults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end